classdef LIVE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhaseLABLiveimagingUIFigure  matlab.ui.Figure
        GridLayout_main              matlab.ui.container.GridLayout
        HTML                         matlab.ui.control.HTML
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout_panelButtons      matlab.ui.container.GridLayout
        PhaseLABButton               matlab.ui.control.StateButton
        controlButton                matlab.ui.control.StateButton
        zcontrolButton               matlab.ui.control.StateButton
        setupButton                  matlab.ui.control.StateButton
        GridLayout_windowsButtons    matlab.ui.container.GridLayout
        closeallButton_2             matlab.ui.control.Button
        canalsButton_2               matlab.ui.control.StateButton
        canalsButton                 matlab.ui.control.StateButton
        RytovButton                  matlab.ui.control.StateButton
        PDCMButton                   matlab.ui.control.StateButton
        dWButton                     matlab.ui.control.StateButton
        massButton                   matlab.ui.control.StateButton
        WButton                      matlab.ui.control.StateButton
        TButton                      matlab.ui.control.StateButton
        rawButton                    matlab.ui.control.StateButton
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout_Imaging_5         matlab.ui.container.GridLayout
        GridLayout_tools             matlab.ui.container.GridLayout
        cropButton                   matlab.ui.control.Button
        radialAvgButton              matlab.ui.control.Button
        pxValueButton                matlab.ui.control.Button
        areaButton                   matlab.ui.control.Button
        pxLabel                      matlab.ui.control.Label
        definitionDropDown           matlab.ui.control.DropDown
        msLabel                      matlab.ui.control.Label
        expTimeEditField             matlab.ui.control.NumericEditField
        tauLabel                     matlab.ui.control.Label
        flattenButton                matlab.ui.control.StateButton
        flattenOrderEditField        matlab.ui.control.NumericEditField
        backOrientationButton        matlab.ui.control.Button
        rot90Button                  matlab.ui.control.Button
        HmirrorButton                matlab.ui.control.Button
        VmirrorButton                matlab.ui.control.Button
        GridLayout_Imaging_4         matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        GridLayout_savePrefix        matlab.ui.container.GridLayout
        EditField                    matlab.ui.control.EditField
        saveFolderButton             matlab.ui.control.Button
        SavePrefixEditField          matlab.ui.control.EditField
        autosaveCheckBox             matlab.ui.control.CheckBox
        savedFileNameEditField       matlab.ui.control.EditField
        Button                       matlab.ui.control.Button
        GridLayout_Imaging_3         matlab.ui.container.GridLayout
        liveAvgEditField             matlab.ui.control.NumericEditField
        refAvgEditField              matlab.ui.control.NumericEditField
        burstCheckBox                matlab.ui.control.CheckBox
        snapAvgEditField             matlab.ui.control.NumericEditField
        SnapButton                   matlab.ui.control.Button
        RefButton                    matlab.ui.control.Button
        LiveButton                   matlab.ui.control.StateButton
        Image                        matlab.ui.control.Image
        GridLayout_setup             matlab.ui.container.GridLayout
        simuButton                   matlab.ui.control.StateButton
        Lamp                         matlab.ui.control.Button
        mmLabel                      matlab.ui.control.Label
        CGdistanceEditField          matlab.ui.control.NumericEditField
        nmLabel                      matlab.ui.control.Label
        RelayLensEditField           matlab.ui.control.NumericEditField
        RelayLensEditFieldLabel      matlab.ui.control.Label
        CrossGratingDropDown         matlab.ui.control.DropDown
        CrossGratingDropDownLabel    matlab.ui.control.Label
        cameraDropDown               matlab.ui.control.DropDown
        cameraDropDownLabel          matlab.ui.control.Label
        microscopeTLDropDown         matlab.ui.control.DropDown
        microscopeTLDropDownLabel    matlab.ui.control.Label
        objBrandDropDown             matlab.ui.control.DropDown
        lambdaEditField              matlab.ui.control.NumericEditField
        lambdaLabel                  matlab.ui.control.Label
        MobjEditField                matlab.ui.control.NumericEditField
        MobjEditFieldLabel           matlab.ui.control.Label
    end


    properties (GetAccess = public, SetAccess = private)
        Itf % current interferogram
        Ref % current reference interferogram
        IM  % current ImageQLSI object
        MI  % current microscope
        IL  % current illumination

        liveOn = 0   % = 1 when live in running
    end

    properties (Dependent)
        Cam % CameraExp object
    end

    properties(SetAccess = private)
        hfigList % struct containing the list of the handles of the open figures
        himList  % struct containing the list of the handles of the displayed images
        haxList  % struct containing the list of the handles of the axes in figures
    end

    properties(Access = private)
        Nx = 1024
        Ny = 1024

        nPos = 0 % position of the open figure, 0, 1 or 2 (1st, 2nd and 3rd lateral third of the screen)

        cameraType {mustBeMember(cameraType,{'','Andor','Hamamatsu','pixelink','PCO'})} = ''

        %  color of the toggle buttons
        colorButtonOn  = [0.5, 0.5, 0.5] % dark
        colorButtonOff = [0.94, 0.94, 0.94] % bright

        lampColorOn = [0.60    0.77    0.60];   % green
        lampColorOff = [0.9 0.9 0.9];  % light gray
        lampColorWait = [0.7 0.5,0.3]; % orange
        lampColorError = [0.8    0.40    0.40];   % red
        lampColorLive = [0.7 0.5,0.3]; % orange

        simuColorOn = [0.60   0.77    0.60];   % green
        simuColorOff = [0.15 0.15 0.15] % dark gray
    end

    properties(Dependent)
        NavgLive % returns the liveAvgEditField value
        NavgRef  % returns the refAvgEditField value
        NavgSnap % returns the sanoAvgEditField value

        expTime % exposure time

        status_hfigList % list of logicalv alues indicating which image windows are open

    end
    
    methods (Access = private)

        function changeButtonColor(app,btn)
            if btn.Value == 1
                % Activated mode
                btn.BackgroundColor = app.colorButtonOn;
                btn.FontColor = [1 1 1];  % White
            else
                % Desactivated mode
                btn.BackgroundColor = app.colorButtonOff ;
                btn.FontColor = [0 0 0];  % Black
            end
        end
        
        function startCamera(app)
            app.lampState('Wait')
            app.Cam.setupSDK()
            rc = app.Cam.startCamera();
            if rc~=0
                disp('error in the camera startup')
                app.lampState('Error');
            else
                app.lampState('On');
            end

        end


        function stopCamera(app)


            app.Lamp.BackgroundColor  = app.lampColorWait;

            switch app.cameraType
                case 'None'
                    app.Lamp.BackgroundColor = [0.2 0.8,0.3];
                    return
                case 'Andor'
                    [rc] = AT_Close(app.Cam.hndl);
                    AT_CheckWarning2(rc,app.TextArea);
                    [rc] = AT_FinaliseLibrary();
                    AT_CheckWarning2(rc,app.TextArea);
                case 'Hamamatsu'

                case 'pixelink'

                case 'PCO'
            end

            app.Lamp.BackgroundColor = app.lampColorOff;

        end

        function enableCameraParameters(app,value)
            % enable or not the parameters in relation with a RL
            arguments
                app
                value %{mustBeMember(value,{"on","off"})}
            end
            app.CrossGratingDropDown.Enable = value;
            app.RelayLensEditField.Enable = value;
            app.CrossGratingDropDownLabel.Enable = value;
            app.RelayLensEditFieldLabel.Enable = value;
            app.CGdistanceEditField.Enable = value;
                        
        end

        function MI2panel(app)
            % update the app.MI with what is written in the parameters panel

            app.MobjEditField.Value = app.MI.Objective.Mobj;   % update Mobj field
            app.cameraDropDown.Value = app.MI.CGcam.fileName;
            app.microscopeTLDropDown.Value = string(app.MI.f_TL) ;
            if isempty(app.MI.CGcam.RL)
                 app.RelayLensEditField.Value = [];
            else
                app.RelayLensEditField.Value = app.MI.CGcam.RL.zoom;
            end
            app.CrossGratingDropDown.Value = app.MI.CGcam.CG.name;

        end

        function panel2MI(app)
            % update the app.MI with what is written in the parameters panel
            % except the camera (update the camera with app.updateCamera() )


            app.MI.Objective = Objective(app.MobjEditField.Value,0.7,app.objBrandDropDown.Value);
            app.MI.f_TL = app.microscopeTLDropDown.Value; 
            app.MI.CGcam.setDistance(app.CGdistanceEditField.Value*1e-3); 
            if isempty(app.RelayLensEditField.Value)
                app.MI.CGcam.RL = RelayLens.empty();
            else
                app.MI.CGcam.RL = RelayLens(app.RelayLensEditField.Value);
            end
            if strcmp(app.CrossGratingDropDown.Value(end-1:end),'µm')
                app.MI.CGcam.CG = CrossGrating(Gamma = str2double(app.CrossGratingDropDown.Value(1:end-2))*1e-6,lambda0 = 550e-9);
            else
                app.MI.CGcam.CG = CrossGrating(app.CrossGratingDropDown.Value);
            end

        end

        function buildWindowsHandles(app)
            % Function that builds the hfig, hax, him properties from the
            % list of button tags ('raw','T','W',...))
            childrens = app.GridLayout_windowsButtons.Children;
            Nt = numel(childrens);
        
            for k = 1:Nt
                element = childrens(k);
                if isa(element, 'matlab.ui.control.StateButton')
                    if ~isempty(element.Tag)
                        app.hfigList.(element.Tag) = [];
                        app.himList.(element.Tag) =  [];
                        app.haxList.(element.Tag) =  [];
                    end
                end
            end
        end
            
        function toggleFigure(app,tag,val) % build or delete the popup figures

            app.([tag,'Button']).Value = val;
            if val % Open the figure
                    [hf,ha,hi] = buildCustomFigure(tag,'Nx',app.Nx,'Ny',app.Ny,'Position',app.nPos,'enableClose',false);
                    app.nPos =  app.nPos+1;
                    app.hfigList.(tag) = hf;
                    app.himList.(tag) = hi;
                    app.haxList.(tag) = ha;
    
            else   % Close the figure
                    app.([tag,'Button']).Value = 0;
                    if isvalid(app.hfigList.(tag))
                        close(app.hfigList.(tag));
                    end
                    delete(app.hfigList.(tag))
                    app.hfigList.(tag) = [];
                    app.himList.(tag) = [];
                    app.haxList.(tag) = [];
            end

            if ~any(app.status_hfigList) % if all the windows are closed
                app.stopLive()
            end
            
            app.changeButtonColor(app.([tag,'Button']));
        end

        function updateCamera(app)
            camName = app.cameraDropDown.Value;

            switch camName
                case {'Zyla5.5','Orca Flash','Panda','OE #17-816','2-color','4-polar','Simu'}
                    app.cameraDropDownLabel.Text = 'Camera';
                    app.enableCameraParameters("on");
                    cam = Camera(strrep(camName, ' ', ''));
                    app.MI.CGcam.Camera = cam.makeAlive();

                case {'sC8-830','sC8-944'}
                    app.MI.CGcam = CGcamera(camName);
                    app.MI.CGcam.Camera = app.MI.CGcam.Camera.makeAlive;
                    app.cameraDropDownLabel.Text = 'CGcamera';  % 'La chaîne contient d'autres caractères que des nombres
                    app.CrossGratingDropDown.Value = 'sC8';
                    app.RelayLensEditField.Value = [];
                    app.CGdistanceEditField.Value = app.MI.CGcam.CGpos*1e3;
                    app.MI2panel()
                    app.enableCameraParameters("off");
            end
        end
        
        function startLive(app)
            app.LiveButton.Value = 1;
            app.LiveButton.Icon = 'STOP.svg';
            app.lampState('live')

            app.liveOn = 1;
            
            app.Cam.startAcquisition(app)
            app.Cam.stopAcquisition()

            app.lampState('On')

            
        end

        function stopLive(app)
            app.LiveButton.Value = 0;
            app.LiveButton.Icon = 'LIVE.svg';
            app.liveOn = 0;
        end

        
    end

    methods % set and get methods

        function val = get.Cam(app)
            val = app.MI.CGcam.Camera;
        end

        function val = get.NavgLive(app)
            val = app.liveAvgEditField.Value;
        end

        function val = get.NavgRef(app)
            val = app.refAvgEditField.Value;
        end

        function val = get.NavgSnap(app)
            val = app.snapAvgEditField.Value;
        end

        function val = get.expTime(app)
            val = app.expTimeEditField.Value;
        end

        function set.expTime(app,val)
            if val ~= app.snapAvgEditField.Value
                app.expTimeEditField.Value = val;
            end
            app.MI.CGcam.Camera.expTime = val;
        end

        function val2 = lampState(app,val)
            % app.lampState("Off")
            % val = app.lampState;
            arguments
                app
                val {mustBeMember(val,{'On','Off','Wait','Error','Live','on','off','wait','live','error',''})} = ''% state of the camera lamp button
            end
            if ~strcmp(val,'')
                val(1) = upper(val(1)); % uppercase the first letter
                app.Lamp.BackgroundColor = app.("lampColor"+val);
                app.Lamp.UserData.state= val;
            elseif nargout
                val2 = app.Lamp.UserData.state;
            end

        end

        function results = get.status_hfigList(app)
            % returns an array of logical values saying which windows are open.
            cellValues = struct2cell(app.hfigList);
            isEmptyLogical = cellfun(@isempty, cellValues);
            results = ~isEmptyLogical;
        end

    end
    methods(Static)
       function setupAndorSDK3()
            % ge the root directory of Matlab
            matlabRootPath = matlabroot;
        
            % Detection of the OS
            if ispc
                % bBuild the path for Windows
                sdkPath = fullfile(matlabRootPath, 'toolbox', 'AndorSDK3');
            elseif ismac
                % Build the path for Mac
                sdkPath = fullfile(matlabRootPath, 'toolbox', 'AndorSDK3');
            else
                error('unsupported OS');
            end
        
            % add the path
            addpath(genpath(sdkPath));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MI, IL)

            if nargin == 3
                app.MI = MI;
                app.IM = IL;
            elseif nargin == 1
                app.MI = [];
                app.IM = [];
            end            
                        
            %% Define the app (figure) position (middle top)
            screenSize = get(0, 'ScreenSize');

            appWidth = app.PhaseLABLiveimagingUIFigure.Position(3);
            appHeight = app.PhaseLABLiveimagingUIFigure.Position(4);

            % Calculate the x position to center horizontally (optional)
            xPos = (screenSize(3) - appWidth) / 2;
            
            % Calculate the y position to align at the top of the screen.
            % Since the coordinate system starts at the bottom left,
            % we set y such that yPos + appHeight = screenSize(4)
            yPos = screenSize(4) - appHeight;

            app.PhaseLABLiveimagingUIFigure.Position(1:2) = [xPos,yPos];
            
            %% Define the UserData of the upper-left state button
            app.Lamp.UserData.colorOn    = app.lampColorOn;
            app.Lamp.UserData.colorOff   = app.lampColorOff;
            app.Lamp.UserData.colorWait  = app.lampColorWait;
            app.Lamp.UserData.colorError = app.lampColorError;
            app.Lamp.UserData.colorLive  = app.lampColorLive;

            app.lampState('Off')

            %% Creating the list of butttons, hlistt
            
            hList = app.GridLayout_panelButtons.Children; % list of all the image type buttons
            for ii = 1:numel(hList)
                app.changeButtonColor(hList(ii));
            end

            %% Creating the app Microscope
            % Creating a blank Microscope
            app.MI = Microscope();
            app.MI.CGcam.CG = CrossGrating();
            app.panel2MI()
            app.RelayLensEditField.AllowEmpty = 'on';
            app.RelayLensEditField.Value = [];
 
            % update the Microscope from the initial panel
            app.updateCamera()
            app.panel2MI()
            
            
            % assign tags to figure handles in app.hfig
            app.buildWindowsHandles();


        end

        % Value changed function: PDCMButton, RytovButton, TButton, 
        % ...and 4 other components
        function toggleImageWindow(app, event)
            % open or close the corresponding figure

            app.toggleFigure(event.Source.Tag,event.Source.Value)

        end

        % Value changed function: CGdistanceEditField, 
        % ...and 5 other components
        function microscopeValueChanged(app, event)
            value = app.microscopeTLDropDown.Value;
            if ~isnan(str2double(value))
                app.microscopeTLDropDownLabel.Text = 'f_TL';
                %str2double(value)  % La chaîne ne contient que des nombres
            else
                app.microscopeTLDropDownLabel.Text = 'Microscope';  % 'La chaîne contient d'autres caractères que des nombres
            end

            app.panel2MI()
        end

        % Value changed function: LiveButton
        function LiveButtonValueChanged(app, event)
            if app.LiveButton.Value                
                app.startLive()
            else
                app.stopLive()
            end
        end

        % Button pushed function: closeallButton_2
        function closeallButtonValueChanged(app, event)
            tags = fields(app.hfigList);
            Na = numel(tags);
            for ia = 1:Na
                if ~isempty(app.hfigList.(tags{ia}))
                    app.toggleFigure(tags{ia},0)
                end
            end
            app.nPos=0;
            app.stopLive()
            
        end

        % Button pushed function: Lamp
        function LampButtonPushed(app, event)
            state = app.Lamp.UserData.state; % 'on' or 'off'
            app.lampState('wait')
            switch state
                case {'on','On'}
                    app.MI.CGcam.Camera.switchCamera('off')  %
                    app.lampState('off')
                case {'off','Off'}
                    rc = app.MI.CGcam.Camera.switchCamera('on');  %
                    if rc == 0
                        app.lampState('on')
                    else
                        app.lampState('error')
                    end
                case {'error','Error'}
                    app.MI.CGcam.Camera.switchCamera('off')  %
                    app.lampState('off')
                otherwise %wait
                    disp('wait')
            end

        end

        % Value changed function: cameraDropDown
        function cameraDropDownValueChanged(app, event)
            app.lampState('Off') % Just to make sure it is off.

            app.updateCamera()
        end

        % Value changed function: simuButton
        function simuButtonValueChanged(app, event)
            value = app.simuButton.Value;
            switch value
                case 1 % on
                    event.Source.BackgroundColor = app.simuColorOn;
                case 0 % off
                    event.Source.BackgroundColor = app.simuColorOff;
            end
        end

        % Value changed function: burstCheckBox
        function burstCheckBoxValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.burstCheckBox.Value;
                app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'BURST.svg');
            else
                app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'SNAP.svg');
            end                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PhaseLABLiveimagingUIFigure = uifigure('Visible', 'off');
            app.PhaseLABLiveimagingUIFigure.Name = 'PhaseLAB - Live imaging';
            app.PhaseLABLiveimagingUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'favicon.png');
            app.GridLayout_main = uigridlayout(app.PhaseLABLiveimagingUIFigure);
            app.GridLayout_setup = uigridlayout(app.GridLayout_main);
            app.MobjEditFieldLabel = uilabel(app.GridLayout_setup);
            app.MobjEditFieldLabel.Text = 'Mobj';
            app.MobjEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.MobjEditField.Limits = [1 Inf];
            app.MobjEditField.RoundFractionalValues = 'on';
            app.MobjEditField.ValueDisplayFormat = '%6.0f';
            app.MobjEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.MobjEditField.Value = 60;
            app.lambdaLabel = uilabel(app.GridLayout_setup);
            app.lambdaLabel.Text = 'λ';
            app.lambdaEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.lambdaEditField.Limits = [100 Inf];
            app.lambdaEditField.Value = 625;
            app.objBrandDropDown = uidropdown(app.GridLayout_setup);
            app.objBrandDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon'};
            app.objBrandDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.objBrandDropDown.Value = 'Olympus';
            app.microscopeTLDropDownLabel = uilabel(app.GridLayout_setup);
            app.microscopeTLDropDownLabel.Text = 'microscope/TL';
            app.microscopeTLDropDown = uidropdown(app.GridLayout_setup);
            app.microscopeTLDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon', '165', '180', '200'};
            app.microscopeTLDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.microscopeTLDropDown.Value = 'Olympus';
            app.cameraDropDownLabel = uilabel(app.GridLayout_setup);
            app.cameraDropDownLabel.Text = 'camera';
            app.cameraDropDown = uidropdown(app.GridLayout_setup);
            app.cameraDropDown.Items = {'Simu', 'Zyla5.5', 'Orca Flash', 'Panda', 'Pixelink', '2-color', '4-polar', 'sC8-830', 'sC8-944'};
            app.cameraDropDown.ValueChangedFcn = createCallbackFcn(app, @cameraDropDownValueChanged, true);
            app.cameraDropDown.Value = 'Simu';
            app.CrossGratingDropDownLabel = uilabel(app.GridLayout_setup);
            app.CrossGratingDropDownLabel.Text = '2D Grating';
            app.CrossGratingDropDown = uidropdown(app.GridLayout_setup);
            app.CrossGratingDropDown.Items = {'F02', 'F03', 'F03b', 'F04', 'P02', 'P04', 'sC8', 'Sid4Element', '39 µm', '35.1 µm', '22.5 µm', '13 µm'};
            app.CrossGratingDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CrossGratingDropDown.Value = 'F02';
            app.RelayLensEditFieldLabel = uilabel(app.GridLayout_setup);
            app.RelayLensEditFieldLabel.Text = 'Relay Lens';
            app.RelayLensEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.RelayLensEditField.Limits = [0 Inf];
            app.RelayLensEditField.ValueDisplayFormat = '%2.2f';
            app.RelayLensEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.RelayLensEditField.Value = 1.11;
            app.nmLabel = uilabel(app.GridLayout_setup);
            app.nmLabel.Text = 'nm';
            app.CGdistanceEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.CGdistanceEditField.ValueDisplayFormat = '%2.3f';
            app.CGdistanceEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CGdistanceEditField.Value = 0.8;
            app.mmLabel = uilabel(app.GridLayout_setup);
            app.mmLabel.Text = 'mm';
            app.Lamp = uibutton(app.GridLayout_setup, 'push');
            app.Lamp.ButtonPushedFcn = createCallbackFcn(app, @LampButtonPushed, true);
            app.Lamp.Text = '';
            app.simuButton = uibutton(app.GridLayout_setup, 'state');
            app.simuButton.ValueChangedFcn = createCallbackFcn(app, @simuButtonValueChanged, true);
            app.simuButton.Text = 'simu';
            app.Image = uiimage(app.GridLayout_main);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'banniere.svg');
            app.GridLayout = uigridlayout(app.GridLayout_main);
            app.GridLayout_Imaging_3 = uigridlayout(app.GridLayout);
            app.LiveButton = uibutton(app.GridLayout_Imaging_3, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonValueChanged, true);
            app.LiveButton.Icon = fullfile(pathToMLAPP, 'icons', 'LIVE.svg');
            app.LiveButton.Text = '';
            app.RefButton = uibutton(app.GridLayout_Imaging_3, 'push');
            app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'REF.svg');
            app.RefButton.Text = '';
            app.SnapButton = uibutton(app.GridLayout_Imaging_3, 'push');
            app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'SNAP.svg');
            app.SnapButton.Text = '';
            app.snapAvgEditField = uieditfield(app.GridLayout_Imaging_3, 'numeric');
            app.snapAvgEditField.Limits = [1 Inf];
            app.snapAvgEditField.RoundFractionalValues = 'on';
            app.snapAvgEditField.ValueDisplayFormat = '%11.0f';
            app.snapAvgEditField.Value = 30;
            app.burstCheckBox = uicheckbox(app.GridLayout_Imaging_3);
            app.burstCheckBox.ValueChangedFcn = createCallbackFcn(app, @burstCheckBoxValueChanged, true);
            app.burstCheckBox.Text = 'burst';
            app.refAvgEditField = uieditfield(app.GridLayout_Imaging_3, 'numeric');
            app.refAvgEditField.Limits = [1 Inf];
            app.refAvgEditField.RoundFractionalValues = 'on';
            app.refAvgEditField.ValueDisplayFormat = '%11.0f';
            app.refAvgEditField.Value = 30;
            app.liveAvgEditField = uieditfield(app.GridLayout_Imaging_3, 'numeric');
            app.liveAvgEditField.Limits = [1 Inf];
            app.liveAvgEditField.RoundFractionalValues = 'on';
            app.liveAvgEditField.ValueDisplayFormat = '%11.0f';
            app.liveAvgEditField.Value = 1;
            app.GridLayout_Imaging_4 = uigridlayout(app.GridLayout);
            app.Button = uibutton(app.GridLayout_Imaging_4, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE.svg');
            app.Button.Text = '';
            app.GridLayout_savePrefix = uigridlayout(app.GridLayout_Imaging_4);
            app.savedFileNameEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.autosaveCheckBox = uicheckbox(app.GridLayout_savePrefix);
            app.autosaveCheckBox.Text = 'autosave';
            app.SavePrefixEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.SavePrefixEditField.Value = 'ITF';
            app.saveFolderButton = uibutton(app.GridLayout_savePrefix, 'push');
            app.saveFolderButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder.svg');
            app.saveFolderButton.Text = '';
            app.EditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.EditField.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout_Imaging_4);
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'icons', 'import.svg');
            app.Button_2.Tooltip = {'import image'};
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout3, 'push');
            app.Button_3.Icon = fullfile(pathToMLAPP, 'icons', 'MOVIE.svg');
            app.Button_3.Tooltip = {'acqiure time lapse'};
            app.Button_3.Text = '';
            app.GridLayout_Imaging_5 = uigridlayout(app.GridLayout);
            app.GridLayout_tools = uigridlayout(app.GridLayout_Imaging_5);
            app.VmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.VmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorV.svg');
            app.VmirrorButton.Tooltip = {'vertical mirror'};
            app.VmirrorButton.Text = '';
            app.HmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.HmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorH.svg');
            app.HmirrorButton.Tooltip = {'Horizontal mirror'};
            app.HmirrorButton.Text = '';
            app.rot90Button = uibutton(app.GridLayout_tools, 'push');
            app.rot90Button.Icon = fullfile(pathToMLAPP, 'icons', 'rot90.svg');
            app.rot90Button.Tooltip = {'Clockwise rotation'};
            app.rot90Button.Text = '';
            app.backOrientationButton = uibutton(app.GridLayout_tools, 'push');
            app.backOrientationButton.Icon = fullfile(pathToMLAPP, 'icons', 'origin.svg');
            app.backOrientationButton.Tooltip = {'Back to native orientation'};
            app.backOrientationButton.Text = '';
            app.flattenOrderEditField = uieditfield(app.GridLayout_tools, 'numeric');
            app.flattenOrderEditField.Limits = [0 3];
            app.flattenOrderEditField.RoundFractionalValues = 'on';
            app.flattenOrderEditField.Tooltip = {'order of the flattening'};
            app.flattenOrderEditField.Value = 2;
            app.flattenButton = uibutton(app.GridLayout_tools, 'state');
            app.flattenButton.Tooltip = {'Flatten'};
            app.flattenButton.Icon = fullfile(pathToMLAPP, 'icons', 'flatten.svg');
            app.flattenButton.Text = '';
            app.tauLabel = uilabel(app.GridLayout_tools);
            app.tauLabel.Text = 'τ';
            app.expTimeEditField = uieditfield(app.GridLayout_tools, 'numeric');
            app.expTimeEditField.Limits = [1 Inf];
            app.expTimeEditField.Value = 80;
            app.msLabel = uilabel(app.GridLayout_tools);
            app.msLabel.Text = 'ms';
            app.definitionDropDown = uidropdown(app.GridLayout_tools);
            app.definitionDropDown.Items = {'2048 x 2048', '2048 x 1720', 'custom'};
            app.definitionDropDown.Value = '2048 x 2048';
            app.pxLabel = uilabel(app.GridLayout_tools);
            app.pxLabel.Text = 'px';
            app.areaButton = uibutton(app.GridLayout_tools, 'push');
            app.areaButton.Icon = fullfile(pathToMLAPP, 'icons', 'areaButton.svg');
            app.areaButton.Tooltip = {'Average value over an area'};
            app.areaButton.Text = '';
            app.pxValueButton = uibutton(app.GridLayout_tools, 'push');
            app.pxValueButton.Icon = fullfile(pathToMLAPP, 'icons', 'pixel.svg');
            app.pxValueButton.Tooltip = {'Pixel value'};
            app.pxValueButton.Text = '';
            app.radialAvgButton = uibutton(app.GridLayout_tools, 'push');
            app.radialAvgButton.Icon = fullfile(pathToMLAPP, 'icons', 'Rcrosscut.svg');
            app.radialAvgButton.Text = '';
            app.cropButton = uibutton(app.GridLayout_tools, 'push');
            app.cropButton.Icon = fullfile(pathToMLAPP, 'icons', 'areaButton.svg');
            app.cropButton.Tooltip = {'Crop the field of view'};
            app.cropButton.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout_main);
            app.GridLayout_windowsButtons = uigridlayout(app.GridLayout2);
            app.rawButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.rawButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.rawButton.Tag = 'raw';
            app.rawButton.Text = 'raw';
            app.TButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.TButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.TButton.Tag = 'T';
            app.TButton.Text = 'T';
            app.WButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.WButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.WButton.Tag = 'W';
            app.WButton.Text = 'W';
            app.massButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.massButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.massButton.Tag = 'mass';
            app.massButton.Text = 'mass';
            app.dWButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.dWButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.dWButton.Tag = 'dW';
            app.dWButton.Text = '∇ W';
            app.PDCMButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.PDCMButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.PDCMButton.Tag = 'PDCM';
            app.PDCMButton.Text = 'PDCM';
            app.RytovButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.RytovButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.RytovButton.Tag = 'Rytov';
            app.RytovButton.Text = 'Rytov';
            app.canalsButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.canalsButton.Tag = 'Rytov';
            app.canalsButton.Text = '2-canals';
            app.canalsButton_2 = uibutton(app.GridLayout_windowsButtons, 'state');
            app.canalsButton_2.Tag = 'Rytov';
            app.canalsButton_2.Text = '4-canals';
            app.closeallButton_2 = uibutton(app.GridLayout_windowsButtons, 'push');
            app.closeallButton_2.ButtonPushedFcn = createCallbackFcn(app, @closeallButtonValueChanged, true);
            app.closeallButton_2.Text = 'close all';
            app.GridLayout_panelButtons = uigridlayout(app.GridLayout2);
            app.setupButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.setupButton.Text = 'setup';
            app.zcontrolButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.zcontrolButton.Text = 'z control';
            app.controlButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.controlButton.Text = 'λ control';
            app.PhaseLABButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.PhaseLABButton.Text = 'PhaseLAB';
            app.HTML = uihtml(app.GridLayout_main);
            app.HTML.HTMLSource = '<a href="https://guillaume.baffou.com">link</a>';
            % Show the figure after all components are created
            app.PhaseLABLiveimagingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIVE(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PhaseLABLiveimagingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PhaseLABLiveimagingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhaseLABLiveimagingUIFigure)
        end
    end
end